classdef TP_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Experiencia1Tab              matlab.ui.container.Tab
        SinRecuantizarButtonGroup    matlab.ui.container.ButtonGroup
        EspectrogramaButton          matlab.ui.control.RadioButton
        MagSpectrumButton            matlab.ui.control.RadioButton
        TiempoButton                 matlab.ui.control.RadioButton
        GuardarButton                matlab.ui.control.Button
        GuardarButton_3              matlab.ui.control.Button
        GuardarButton_2              matlab.ui.control.Button
        ReproducirButton_3           matlab.ui.control.Button
        ReproducirButton_2           matlab.ui.control.Button
        CuantizacionAdaptivaButtonGroup  matlab.ui.container.ButtonGroup
        rp5Button_2                  matlab.ui.control.RadioButton
        SNRBloquesButton_2           matlab.ui.control.RadioButton
        SNRTotalButton_2             matlab.ui.control.RadioButton
        rp4Button_2                  matlab.ui.control.RadioButton
        rp3Button_2                  matlab.ui.control.RadioButton
        rp2Button_2                  matlab.ui.control.RadioButton
        rp1Button_2                  matlab.ui.control.RadioButton
        CuantizacionFijaButtonGroup  matlab.ui.container.ButtonGroup
        rp5Button                    matlab.ui.control.RadioButton
        SNRBloquesButton             matlab.ui.control.RadioButton
        SNRTotalButton               matlab.ui.control.RadioButton
        rp4Button                    matlab.ui.control.RadioButton
        rp3Button                    matlab.ui.control.RadioButton
        rp2Button                    matlab.ui.control.RadioButton
        rp1Button                    matlab.ui.control.RadioButton
        CuantizarButton              matlab.ui.control.Button
        ReproducirButton             matlab.ui.control.Button
        rp5EditField                 matlab.ui.control.EditField
        rp5EditFieldLabel            matlab.ui.control.Label
        rp4EditField                 matlab.ui.control.EditField
        rp4EditFieldLabel            matlab.ui.control.Label
        rp3EditField                 matlab.ui.control.EditField
        rp3EditFieldLabel            matlab.ui.control.Label
        rp2EditField                 matlab.ui.control.EditField
        rp2EditFieldLabel            matlab.ui.control.Label
        rp1EditField                 matlab.ui.control.EditField
        rp1EditFieldLabel            matlab.ui.control.Label
        TbTimeblockEditField         matlab.ui.control.EditField
        TbTimeblockEditFieldLabel    matlab.ui.control.Label
        TcCapturetimeEditField       matlab.ui.control.EditField
        TcCapturetimeEditFieldLabel  matlab.ui.control.Label
        CaptureLabel                 matlab.ui.control.Label
        QuantizationLabel            matlab.ui.control.Label
        OutputDropDown               matlab.ui.control.DropDown
        OutputDropDownLabel          matlab.ui.control.Label
        InputDropDown                matlab.ui.control.DropDown
        InputDropDownLabel           matlab.ui.control.Label
        IOSelectionLabel             matlab.ui.control.Label
        RecordButton                 matlab.ui.control.Button
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        inputdict
        outputdict
        canquant = [0 0 0 0 0 0]
        quant = struct('fixed', 0, 'adaptive', 0)
        rec = struct('Fs', 8000, 'r', 24, 'recobj', 0, 'raw', 0, 't', 0)
        SNR = struct('total_fixed', 0, 'blocks_fixed', 0, 'total_adaptive', 0, 'blocks_adaptive', 0)
        SNRbf_axis = struct('x', 0 , 'y', 0)
        SNRba_axis = struct('x', 0 , 'y', 0)
        levels = [0 0 0 0 0]

    end
    
    methods (Access = public)
        
        function [inputdict, outputdict] = get_devs(app)
            devices = audiodevinfo();

            input = convertCharsToStrings({devices.input.Name});
            input_ids = [devices.input.ID];
            inputdict = dictionary(input, input_ids); % Devices
            
            output = convertCharsToStrings({devices.output.Name});
            output_ids = [devices.output.ID];
            outputdict = dictionary(output, output_ids); % Devices
            
        end


        % Graph formatters
        function format_graphs(app, xlim, xticks, xlabel, ylim, yticks, ylabel, varargin)
            %xlim, xticks, ylim and yticks must be arrays containing arrays
            %xlabel, ylabel son un string array

            if length(varargin) == 1
                %Eje X
                disp('hola')
                disp(varargin{1})
                varargin{1}.XGrid = "on";
                varargin{1}.XLim = xlim;
                varargin{1}.XTick = xticks;
                varargin{1}.XLabel.String = xlabel;

                %Eje Y
                varargin{1}.YGrid = "on";
                varargin{1}.YLim = ylim;
                varargin{1}.YTick = yticks;
                varargin{1}.YLabel.String = ylabel;
                return
            end

            for i=1:length(varargin)
                %Eje X
                varargin{i}.XGrid = "on";
                varargin{i}.XLim = xlim(i, :);
                varargin{i}.XTick = xticks(i, :);
                varargin{i}.XLabel.String = xlabel(i);

                %Eje Y
                varargin{i}.YGrid = "on";
                varargin{i}.YLim = ylim(i, :);
                varargin{i}.YTick = yticks(i, :);
                varargin{i}.YLabel.String = ylabel(i);
            end
        end

        function clear_graph(app, varargin)
            cellfun(@cla, varargin)
        end


        %% Button toggles
        function toggle_button(app, varargin) % Invierte el estado del boton
            for i=1:length(varargin)
                button = varargin{i};
                state = get(button, 'Enable');
                set(button, 'Enable', ~state);
            end
        end

        function toggle_button_on(app, varargin) % Activa el boton
            for i=1:length(varargin)
                button = varargin{i};
                set(button, 'Enable', 'on');
            end
        end

        function toggle_button_off(app, varargin) % Desactiva el boton
            for i=1:length(varargin)
                button = varargin{i};
                set(button, 'Enable', 'off');
            end
        end

        function quant_enable(app) % Revisa si se cumplen las condiciones para inciar la recuantizacion
            if all(app.canquant) && any(app.rec.raw)
                set(app.CuantizarButton, 'Enable', 'on')
            else
                set(app.CuantizarButton, 'Enable', 'off')
            end
        end
        

        function button_text(app, button, text) % Cambiar texto del boton
            set(button, 'Text', text);
        end
      

        %% Input validation
        function val = num_invalid(app, num)
            if isnan(num) || num <= 0
                warndlg('El valor debe ser un numero, y mayor a 0')
                val = 1;
            else
                val = 0;
            end
        end

        function [xaxis, yaxis] = quantselect(app, tipo, selected) % Seleccion de tipo de grafico
            graphobj = 0;
            if strcmp(tipo, 'fixed')
                graphobj = app.UIAxes_2;
            elseif strcmp(tipo, 'adaptive')
                graphobj = app.UIAxes_3;
            end

            if strcmp(selected.Text(1:2), 'rp') & strcmp(tipo, 'fixed')

                rp_id = str2num(selected.Text(end));
                Tc = str2double(app.TcCapturetimeEditField.Value);
                xaxis = app.rec.t;
                xlim = [0 Tc];
                xticks = 0:0.5:Tc;
                xtitle = 'Tiempo (s)';
                yaxis = app.quant.fixed(rp_id, :);
                ylim = [-1 1];
                yticks = -1:0.5:1;
                ytitle = 'Amplitud';

                app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graphobj)
                plot(graphobj, xaxis, yaxis)

            elseif strcmp(selected.Text(1:2), 'rp') & strcmp(tipo, 'adaptive')

                rp_id = str2num(selected.Text(end));
                Tc = str2double(app.TcCapturetimeEditField.Value);
                xaxis = app.rec.t;
                xlim = [0 Tc];
                xticks = 0:0.5:Tc;
                xtitle = 'Tiempo (s)';
                yaxis = app.quant.adaptive(rp_id, :);
                ylim = [-1 1];
                yticks = -1:0.5:1;
                ytitle = 'Amplitud';

                app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graphobj)
                plot(graphobj, xaxis, yaxis)

            elseif strcmp(selected.Text, 'SNR Total') & strcmp(tipo, 'fixed')
                xaxis = app.levels;
                xlim = [min(app.levels) max(app.levels)];
                xticks = min(app.levels):max(app.levels);
                xtitle = 'rp';
                yaxis = app.SNR.total_fixed;
                ylim = [0 100];
                yticks = 0:20:100;
                ytitle = 'SNR_d_b';

                app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graphobj)
                stem(graphobj, xaxis, yaxis);

            elseif strcmp(selected.Text, 'SNR Total') & strcmp(tipo, 'adaptive')
                xaxis = app.levels;
                xlim = [min(app.levels) max(app.levels)];
                xticks = min(app.levels):max(app.levels);
                xtitle = 'rp';
                yaxis = app.SNR.total_adaptive;
                ylim = [0 100];
                yticks = 0:20:100;
                ytitle = 'SNR_d_b';

                app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graphobj)
                stem(graphobj, xaxis, yaxis);

            elseif strcmp(selected.Text, 'SNR Bloques') & strcmp(tipo, 'fixed')
                xaxis = app.SNRbf_axis.x;
                xlim = [0 max(xaxis)];
                steps = round(length(xaxis) / 10);
                xticks = 0:steps:max(xaxis);
                xtitle = 'Numero de bloque';
                yaxis = app.SNRbf_axis.y;
                ylim = [0 100];
                yticks = 0:20:100;
                ytitle = 'SNR_d_b';

                app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graphobj)
                plot(graphobj, xaxis, yaxis(1, :), 'r')
                hold(graphobj, 'on');
                plot(graphobj, xaxis, yaxis(2, :), 'g')
                plot(graphobj, xaxis, yaxis(3, :), 'b')
                plot(graphobj, xaxis, yaxis(4, :), 'y')
                plot(graphobj, xaxis, yaxis(5, :), 'k')
                hold(graphobj, 'off');
            elseif strcmp(selected.Text, 'SNR Bloques') & strcmp(tipo, 'adaptive')
                xaxis = app.SNRba_axis.x;
                xlim = [0 max(xaxis)];
                steps = round(length(xaxis) / 10);
                xticks = 0:steps:max(xaxis);
                xtitle = 'Numero de bloque';
                yaxis = app.SNRba_axis.y;
                ylim = [0 100];
                yticks = 0:20:100;
                ytitle = 'SNR_d_b';

                app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graphobj)
                plot(graphobj, xaxis, yaxis(1, :), 'r')
                hold(graphobj, 'on');
                plot(graphobj, xaxis, yaxis(2, :), 'g')
                plot(graphobj, xaxis, yaxis(3, :), 'b')
                plot(graphobj, xaxis, yaxis(4, :), 'y')
                plot(graphobj, xaxis, yaxis(5, :), 'k')
                hold(graphobj, 'off');
            end
        end
        
        function results = rawselect(app, selected)
            graphobj = app.UIAxes;
            value = str2double(app.TcCapturetimeEditField.Value);
            if strcmp(selected.Text(1:6), 'Tiempo')
                app.clear_graph(graphobj)
                Tc = str2double(app.TcCapturetimeEditField.Value);
                xaxis = app.rec.t;
                xlim = [0 Tc];
                xticks = 0:0.5:Tc;
                xtitle = 'Tiempo (s)';
                yaxis = app.rec.raw;
                ylim = [-1 1];
                yticks = -1:0.5:1;
                ytitle = 'Amplitud';

                app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graphobj)
                graphobj.XScale = 'linear';
                graphobj.XTick = xticks;
                graphobj.XLim = xlim;
                disp(graphobj)
                plot(app.UIAxes, xaxis, yaxis)
                grid on;

            elseif strcmp(selected.Text(1:6), 'Espect')
                app.clear_graph(graphobj)
                Fs = app.rec.Fs;
                tiempo_ventana = 30*1e-3;
                tiempo_traslape = 0;
                tam_ventana = Fs*tiempo_ventana;
                tam_traslape = Fs*tiempo_traslape;
                nfft = 2^nextpow2(tam_ventana);


                [S, F, T] = spectrogram(app.rec.raw, tam_ventana, tam_traslape, nfft, Fs, 'yaxis');

                Tc = str2double(app.TcCapturetimeEditField.Value);
                xaxis = app.rec.t;
                xlim = [0 Tc];
                xticks = 0:0.5:Tc;
                xtitle = 'Tiempo (s)';
                yaxis = F;
                ylim = [0 max(F)];
                yticks = 0:1000:max(F);
                ytitle = 'Frecuencia (Hz)';
                graphobj.XScale = 'linear';
                app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graphobj)

                spectrogram(app.rec.raw, tam_ventana, tam_traslape, nfft, Fs, 'yaxis');
                imagesc(graphobj, T, F, log(1+abs(S)));
                set(graphobj, 'YDir', 'normal')
                axis square
                axis tight
                colorbar
            elseif strcmp(selected.Text(1:6), 'MagSpe')
                app.clear_graph(graphobj)
                raw = app.rec.raw;
                Nx = length(app.rec.raw);
                X = fft(raw);                 % Transformación de Fourier
                X = X(1:Nx/2+1);            % Frecuencias positivas
                fx = (0:Nx/2)' * app.rec.Fs/Nx;     % Dominio de frecuencia de la señal de audio

                xaxis = fx;
                % xlim = [.1 max(xaxis)];
                % xticks = 0:1000:max(xaxis);
                % xtitle = 'Frecuencia (Hz)';
                yaxis = abs(X);
                % ylim = [min(yaxis) max(yaxis)];
                % yticks = min(yaxis):max(yaxis);
                % ytitle = 'Amplitud';
                % app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graphobj)

                graphobj.XLim = [.1 4];
                graphobj.XTick = 2.^(-2:2);
                graphobj.XScale = 'log';
                graphobj.XTickLabel = 2.^(-2:2);
                graphobj.YLim = [-100 100];
                graphobj.YTick = -100:50:100;
                graphobj.XLabel.String = 'f (kHz)';
                graphobj.YLabel.String = 'Amplitud';
                plot(graphobj, xaxis*1e-3, 20*log10(yaxis));
                grid on;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Populate input and output drop down settings
            [app.inputdict, app.outputdict] = app.get_devs(); 
            app.InputDropDown.Items = cellstr(app.inputdict.keys)';
            app.OutputDropDown.Items = cellstr(app.outputdict.keys)';

            % Format axes
            xlim = [0 1];
            xticks = 0:0.5:1;
            xlabel = "Tiempo (s)";
            ylim = [-1 1];
            yticks = -1:0.5:1;
            ylabel = "Amplitud";
            app.format_graphs([xlim;xlim;xlim], ...
                [xticks;xticks;xticks], ...
                [xlabel;xlabel;xlabel], ...
                [ylim;ylim;ylim], ...
                [yticks;yticks;yticks], ...
                [ylabel;ylabel;ylabel], ...
                app.UIAxes, ...
                app.UIAxes_2, ...
                app.UIAxes_3);

            % Start all raw audio buttons as disabled
            app.toggle_button_off(app.RecordButton, ...
                app.ReproducirButton, ...
                app.GuardarButton, ...
                app.CuantizarButton)

            % Start all cuantization buttons as disabled
            app.toggle_button_off(app.CuantizacionFijaButtonGroup, ...
                app.CuantizacionAdaptivaButtonGroup, ...
                app.ReproducirButton_2, ...
                app.ReproducirButton_3, ...
                app.GuardarButton_2, ...
                app.GuardarButton_3)
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            Tc = str2double(app.TcCapturetimeEditField.Value);
            fs = app.rec.Fs;
            r = app.rec.r;
            ID = app.inputdict(app.InputDropDown.Value);

            % Disable record and reproducir buttons mientras espero
            app.toggle_button_off(app.RecordButton, app.ReproducirButton)
            app.button_text(app.RecordButton, 'Grabando...')

            % Start recording
            app.rec.recobj = grabar_audio(Tc, fs, r, ID);

            % Recording finished
            app.toggle_button_on(app.RecordButton) % Pasa de disabled a enabled
            app.button_text(app.RecordButton, 'Record')

            % Get audio data
            app.rec.raw = getaudiodata(app.rec.recobj)';
            %audiowrite('raw_recording.wav', app.rec.raw, fs); % Write recording to file

            % Habilitar reproducir y guardado ya que existe archivo
            app.toggle_button_on(app.ReproducirButton, app.GuardarButton)
            app.quant_enable(); % Intentar habilitar cuantizacion

            % Plot recording
            app.rec.t = 0:1/fs:Tc - 1/fs;
            plot(app.UIAxes, app.rec.t, app.rec.raw);
        end

        % Clicked callback: InputDropDown
        function InputDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
        end

        % Value changed function: TcCapturetimeEditField
        function TcCapturetimeEditFieldValueChanged(app, event)
            value = str2double(app.TcCapturetimeEditField.Value);
            if app.num_invalid(value)
                set(app.TcCapturetimeEditField, 'Value', '')

                % Clear plot if any
                app.clear_graph(app.UIAxes, app.UIAxes_2, app.UIAxes_3)

                % Limpio grabacion previa si hubiera
                app.rec.recobj = 0;
                app.rec.raw = 0;

                % Deshabilito reproducir, guardar y cuantizar porque se
                % limpio la grabación
                app.toggle_button_off(app.ReproducirButton, ...
                    app.GuardarButton, ...
                    app.CuantizarButton);
                return
            end
            
           % Enable record button
            app.toggle_button_on(app.RecordButton)

           % Limpio grabacion previa si hubiera
            app.rec.recobj = 0;
            app.rec.raw = 0;
            app.quant.fixed = 0;
            app.quant.adaptive = 0;

            % Deshabilito reproducir, guardar y cuantizar en todas las secciones
            % porque se limpio la grabación
            app.toggle_button_off(app.ReproducirButton, ...
                app.GuardarButton, ...
                app.CuantizarButton)

            % Deshabilito botones de otras secciones por la misma razon
            app.toggle_button_off(app.CuantizacionFijaButtonGroup, ...
                app.CuantizacionAdaptivaButtonGroup, ...
                app.ReproducirButton_2, ...
                app.ReproducirButton_3, ...
                app.GuardarButton_2, ...
                app.GuardarButton_3)

            % Configure axes
            app.clear_graph(app.UIAxes, app.UIAxes_2, app.UIAxes_3) % Clear plot if any
            xlim = [0 value];
            xticks = 0:0.5:value;
            xlabel = "Tiempo (s)";
            ylim = [-1 1];
            yticks = -1:0.5:1;
            ylabel = "Amplitud";
            app.format_graphs([xlim;xlim;xlim], ...
                [xticks;xticks;xticks], ...
                [xlabel;xlabel;xlabel], ...
                [ylim;ylim;ylim], ...
                [yticks;yticks;yticks], ...
                [ylabel;ylabel;ylabel], ...
                app.UIAxes, ...
                app.UIAxes_2, ...
                app.UIAxes_3);
            app.UIAxes.XLim = [0 value];
            app.UIAxes.XTick = 0:0.5:value;
        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
            % Deshabilitar botones durante la reproduccion
            app.toggle_button_off(app.RecordButton, app.ReproducirButton)
            sound(app.rec.raw, app.rec.Fs)
            pause(length(app.rec.raw) * 1/app.rec.Fs)

            % Habilitar botones despues de la reproduccion
            app.toggle_button_on(app.RecordButton, app.ReproducirButton)
        end

        % Value changed function: TbTimeblockEditField
        function TbTimeblockEditFieldValueChanged(app, event)
            value = str2double(app.TbTimeblockEditField.Value);
            if app.num_invalid(value)
                set(app.TbTimeblockEditField, 'Value', '')
                app.canquant(6) = 0;
                app.quant_enable();
                return
            end
            app.canquant(6) = 1;
            app.quant_enable();
        end

        % Value changed function: rp1EditField
        function rp1EditFieldValueChanged(app, event)
            value = str2double(app.rp1EditField.Value);
            if app.num_invalid(value)
                set(app.rp1EditField, 'Value', '')
                app.canquant(1) = 0;
                app.quant_enable();
                return
            end

            if value ~= floor(value)
                set(app.rp1EditField, 'Value', '')
                app.canquant(1) = 0;
                app.quant_enable();
                warndlg('rp debe ser un numero entero')
                return
            end
            
            app.levels(1) = value;

            app.canquant(1) = 1;
            app.quant_enable();
        end

        % Value changed function: rp2EditField
        function rp2EditFieldValueChanged(app, event)
            value = str2double(app.rp2EditField.Value);
            if app.num_invalid(value)
                set(app.rp2EditField, 'Value', '')
                app.canquant(2) = 0;
                app.quant_enable();
                return
            end

            if value ~= floor(value)
                set(app.rp2EditField, 'Value', '')
                app.canquant(2) = 0;
                app.quant_enable();
                warndlg('rp debe ser un numero entero')
                return
            end
            
            app.levels(2) = value;

            app.canquant(2) = 1;
            app.quant_enable();
        end

        % Value changed function: rp3EditField
        function rp3EditFieldValueChanged(app, event)
            value = str2double(app.rp3EditField.Value);
            if app.num_invalid(value)
                set(app.rp3EditField, 'Value', '')
                app.canquant(3) = 0;
                app.quant_enable();
                return
            end

            if value ~= floor(value)
                set(app.rp3EditField, 'Value', '')
                app.canquant(3) = 0;
                app.quant_enable();
                warndlg('rp debe ser un numero entero')
                return
            end

            app.levels(3) = value;

            app.canquant(3) = 1;
            app.quant_enable();
        end

        % Value changed function: rp4EditField
        function rp4EditFieldValueChanged(app, event)
            value = str2double(app.rp4EditField.Value);
            if app.num_invalid(value)
                set(app.rp4EditField, 'Value', '')
                app.canquant(4) = 0;
                app.quant_enable();
                return
            end

            if value ~= floor(value)
                set(app.rp4EditField, 'Value', '')
                app.canquant(4) = 0;
                app.quant_enable();
                warndlg('rp debe ser un numero entero')
                return
            end

            app.levels(4) = value;

            app.canquant(4) = 1;
            app.quant_enable();
        end

        % Value changed function: rp5EditField
        function rp5EditFieldValueChanged(app, event)
            value = str2double(app.rp5EditField.Value);
            if app.num_invalid(value)
                set(app.rp5EditField, 'Value', '')
                app.canquant(5) = 0;
                app.quant_enable();
                return
            end

            if value ~= floor(value)
                set(app.rp5EditField, 'Value', '')
                app.canquant(5) = 0;
                app.quant_enable();
                warndlg('rp debe ser un numero entero')
                return
            end

            app.levels(5) = value;

            app.canquant(5) = 1;
            app.quant_enable();
        end

        % Button pushed function: CuantizarButton
        function CuantizarButtonPushed(app, event)
            Tb = str2double(app.TbTimeblockEditField.Value);

            % Realizar calculos de cuantizacion
            app.quant.fixed = fixed_quantization(app.rec.raw, app.levels, 1);
            app.quant.adaptive = adaptive_quantization(app.rec.raw, app.levels, Tb, app.rec.Fs);

            % Realizar calculos de SNR
            app.SNR.total_fixed = get_snr(app.rec.raw, app.quant.fixed)';
            [app.SNRbf_axis.x, app.SNRbf_axis.y] = block_snr(app.rec.raw, app.quant.fixed, app.rec.Fs, Tb);

            app.SNR.total_adaptive = get_snr(app.rec.raw, app.quant.adaptive)';
            [app.SNRba_axis.x, app.SNRba_axis.y] = block_snr(app.rec.raw, app.quant.adaptive, app.rec.Fs, Tb);

            % Clear graphs
            app.clear_graph(app.UIAxes_2, app.UIAxes_3)

            % Plot for rp1 by default
            app.CuantizacionFijaButtonGroup.SelectedObject = app.rp1Button; % Set rp1 as selected button
            app.CuantizacionAdaptivaButtonGroup.SelectedObject = app.rp1Button_2; % Set rp1 as selected button
            plot(app.UIAxes_2, app.rec.t, app.quant.fixed(1, :));
            plot(app.UIAxes_3, app.rec.t, app.quant.adaptive(1, :));

            % Enable all quantization buttons
            app.toggle_button_on(app.CuantizacionFijaButtonGroup, ...
                app.CuantizacionAdaptivaButtonGroup, ...
                app.ReproducirButton_2, ...
                app.ReproducirButton_3, ...
                app.GuardarButton_2, ...
                app.GuardarButton_3)
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            audiowrite('raw_voice.wav', app.rec.raw, app.rec.Fs);
        end

        % Selection changed function: CuantizacionFijaButtonGroup
        function CuantizacionFijaButtonGroupSelectionChanged(app, event)
            selected = app.CuantizacionFijaButtonGroup.SelectedObject;
            app.quantselect('fixed', selected);

            if strcmp(selected.Text(1:2), 'rp')
                app.toggle_button_on(app.ReproducirButton_2, app.GuardarButton_2)
            else
                app.toggle_button_off(app.ReproducirButton_2, app.GuardarButton_2)
            end
        end

        % Button pushed function: ReproducirButton_2
        function ReproducirButton_2Pushed(app, event)
            selected = app.CuantizacionFijaButtonGroup.SelectedObject;
            [~, y] = app.quantselect('fixed', selected);

            % Deshabilitar botones durante la reproduccion
            app.toggle_button_off(app.ReproducirButton_2)
            sound(y, app.rec.Fs)
            pause(length(y) * 1/app.rec.Fs)

            % Habilitar botones despues de la reproduccion
            app.toggle_button_on(app.ReproducirButton_2)
        end

        % Button pushed function: GuardarButton_2
        function GuardarButton_2Pushed(app, event)
            selected = app.CuantizacionFijaButtonGroup.SelectedObject;
            [~, y] = app.quantselect('fixed', selected);
            name = sprintf('fixed_%s.wav', selected.Text);
            audiowrite(name, y, app.rec.Fs);
        end

        % Selection changed function: CuantizacionAdaptivaButtonGroup
        function CuantizacionAdaptivaButtonGroupSelectionChanged(app, event)
            selected = app.CuantizacionAdaptivaButtonGroup.SelectedObject;
            app.quantselect('adaptive', selected);

            if strcmp(selected.Text(1:2), 'rp')
                app.toggle_button_on(app.ReproducirButton_3, app.GuardarButton_3)
            else
                app.toggle_button_off(app.ReproducirButton_3, app.GuardarButton_3)
            end
        end

        % Button pushed function: ReproducirButton_3
        function ReproducirButton_3Pushed(app, event)
            selected = app.CuantizacionAdaptivaButtonGroup.SelectedObject;
            [~, y] = app.quantselect('adaptive', selected);

            % Deshabilitar botones durante la reproduccion
            app.toggle_button_off(app.ReproducirButton_3)
            sound(y, app.rec.Fs)
            pause(length(y) * 1/app.rec.Fs)

            % Habilitar botones despues de la reproduccion
            app.toggle_button_on(app.ReproducirButton_3)
        end

        % Button pushed function: GuardarButton_3
        function GuardarButton_3Pushed(app, event)
            selected = app.CuantizacionAdaptivaButtonGroup.SelectedObject;
            [~, y] = app.quantselect('adaptive', selected);
            name = sprintf('adaptive_%s.wav', selected.Text);
            audiowrite(name, y, app.rec.Fs);
        end

        % Selection changed function: SinRecuantizarButtonGroup
        function SinRecuantizarButtonGroupSelectionChanged(app, event)
            selected = app.SinRecuantizarButtonGroup.SelectedObject;
            app.rawselect(selected);
            
            if strcmp(selected.Text(1:6), 'Tiempo')
                app.toggle_button_on(app.ReproducirButton, app.GuardarButton)
            else
                app.toggle_button_off(app.ReproducirButton, app.GuardarButton)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Experiencia1Tab = uitab(app.TabGroup);
            app.Experiencia1Tab.Title = 'Experiencia 1';
            app.UIAxes = uiaxes(app.Experiencia1Tab);
            title(app.UIAxes, 'Grabacion')
            xlabel(app.UIAxes, 'Tiempo')
            ylabel(app.UIAxes, 'Amplitud')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.Experiencia1Tab);
            title(app.UIAxes_2, 'Fijo')
            xlabel(app.UIAxes_2, 'Tiempo')
            ylabel(app.UIAxes_2, 'Amplitud')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.Experiencia1Tab);
            title(app.UIAxes_3, 'Adaptivo')
            xlabel(app.UIAxes_3, 'Tiempo')
            ylabel(app.UIAxes_3, 'Amplitud')
            zlabel(app.UIAxes_3, 'Z')
            app.RecordButton = uibutton(app.Experiencia1Tab, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.IOSelectionLabel = uilabel(app.Experiencia1Tab);
            app.IOSelectionLabel.Text = 'I/O Selection';
            app.InputDropDownLabel = uilabel(app.Experiencia1Tab);
            app.InputDropDownLabel.Text = 'Input';
            app.InputDropDown = uidropdown(app.Experiencia1Tab);
            app.InputDropDown.ClickedFcn = createCallbackFcn(app, @InputDropDownClicked, true);
            app.OutputDropDownLabel = uilabel(app.Experiencia1Tab);
            app.OutputDropDownLabel.Text = 'Output';
            app.OutputDropDown = uidropdown(app.Experiencia1Tab);
            app.QuantizationLabel = uilabel(app.Experiencia1Tab);
            app.QuantizationLabel.Text = 'Quantization';
            app.CaptureLabel = uilabel(app.Experiencia1Tab);
            app.CaptureLabel.Text = 'Capture';
            app.TcCapturetimeEditFieldLabel = uilabel(app.Experiencia1Tab);
            app.TcCapturetimeEditFieldLabel.Text = 'Tc (Capture time)';
            app.TcCapturetimeEditField = uieditfield(app.Experiencia1Tab, 'text');
            app.TcCapturetimeEditField.ValueChangedFcn = createCallbackFcn(app, @TcCapturetimeEditFieldValueChanged, true);
            app.TbTimeblockEditFieldLabel = uilabel(app.Experiencia1Tab);
            app.TbTimeblockEditFieldLabel.Text = 'Tb (Time block)';
            app.TbTimeblockEditField = uieditfield(app.Experiencia1Tab, 'text');
            app.TbTimeblockEditField.ValueChangedFcn = createCallbackFcn(app, @TbTimeblockEditFieldValueChanged, true);
            app.rp1EditFieldLabel = uilabel(app.Experiencia1Tab);
            app.rp1EditFieldLabel.Text = 'rp1';
            app.rp1EditField = uieditfield(app.Experiencia1Tab, 'text');
            app.rp1EditField.ValueChangedFcn = createCallbackFcn(app, @rp1EditFieldValueChanged, true);
            app.rp2EditFieldLabel = uilabel(app.Experiencia1Tab);
            app.rp2EditFieldLabel.Text = 'rp2';
            app.rp2EditField = uieditfield(app.Experiencia1Tab, 'text');
            app.rp2EditField.ValueChangedFcn = createCallbackFcn(app, @rp2EditFieldValueChanged, true);
            app.rp3EditFieldLabel = uilabel(app.Experiencia1Tab);
            app.rp3EditFieldLabel.Text = 'rp3';
            app.rp3EditField = uieditfield(app.Experiencia1Tab, 'text');
            app.rp3EditField.ValueChangedFcn = createCallbackFcn(app, @rp3EditFieldValueChanged, true);
            app.rp4EditFieldLabel = uilabel(app.Experiencia1Tab);
            app.rp4EditFieldLabel.Text = 'rp4';
            app.rp4EditField = uieditfield(app.Experiencia1Tab, 'text');
            app.rp4EditField.ValueChangedFcn = createCallbackFcn(app, @rp4EditFieldValueChanged, true);
            app.rp5EditFieldLabel = uilabel(app.Experiencia1Tab);
            app.rp5EditFieldLabel.Text = 'rp5';
            app.rp5EditField = uieditfield(app.Experiencia1Tab, 'text');
            app.rp5EditField.ValueChangedFcn = createCallbackFcn(app, @rp5EditFieldValueChanged, true);
            app.ReproducirButton = uibutton(app.Experiencia1Tab, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.CuantizarButton = uibutton(app.Experiencia1Tab, 'push');
            app.CuantizarButton.ButtonPushedFcn = createCallbackFcn(app, @CuantizarButtonPushed, true);
            app.CuantizarButton.Text = 'Cuantizar';
            app.CuantizacionFijaButtonGroup = uibuttongroup(app.Experiencia1Tab);
            app.CuantizacionFijaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CuantizacionFijaButtonGroupSelectionChanged, true);
            app.CuantizacionFijaButtonGroup.Title = 'Cuantizacion Fija';
            app.rp1Button = uiradiobutton(app.CuantizacionFijaButtonGroup);
            app.rp1Button.Text = 'rp1';
            app.rp1Button.Value = true;
            app.rp2Button = uiradiobutton(app.CuantizacionFijaButtonGroup);
            app.rp2Button.Text = 'rp2';
            app.rp3Button = uiradiobutton(app.CuantizacionFijaButtonGroup);
            app.rp3Button.Text = 'rp3';
            app.rp4Button = uiradiobutton(app.CuantizacionFijaButtonGroup);
            app.rp4Button.Text = 'rp4';
            app.SNRTotalButton = uiradiobutton(app.CuantizacionFijaButtonGroup);
            app.SNRTotalButton.Text = 'SNR Total';
            app.SNRBloquesButton = uiradiobutton(app.CuantizacionFijaButtonGroup);
            app.SNRBloquesButton.Text = 'SNR Bloques';
            app.rp5Button = uiradiobutton(app.CuantizacionFijaButtonGroup);
            app.rp5Button.Text = 'rp5';
            app.CuantizacionAdaptivaButtonGroup = uibuttongroup(app.Experiencia1Tab);
            app.CuantizacionAdaptivaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CuantizacionAdaptivaButtonGroupSelectionChanged, true);
            app.CuantizacionAdaptivaButtonGroup.Title = 'Cuantizacion Adaptiva';
            app.rp1Button_2 = uiradiobutton(app.CuantizacionAdaptivaButtonGroup);
            app.rp1Button_2.Text = 'rp1';
            app.rp1Button_2.Value = true;
            app.rp2Button_2 = uiradiobutton(app.CuantizacionAdaptivaButtonGroup);
            app.rp2Button_2.Text = 'rp2';
            app.rp3Button_2 = uiradiobutton(app.CuantizacionAdaptivaButtonGroup);
            app.rp3Button_2.Text = 'rp3';
            app.rp4Button_2 = uiradiobutton(app.CuantizacionAdaptivaButtonGroup);
            app.rp4Button_2.Text = 'rp4';
            app.SNRTotalButton_2 = uiradiobutton(app.CuantizacionAdaptivaButtonGroup);
            app.SNRTotalButton_2.Text = 'SNR Total';
            app.SNRBloquesButton_2 = uiradiobutton(app.CuantizacionAdaptivaButtonGroup);
            app.SNRBloquesButton_2.Text = 'SNR Bloques';
            app.rp5Button_2 = uiradiobutton(app.CuantizacionAdaptivaButtonGroup);
            app.rp5Button_2.Text = 'rp5';
            app.ReproducirButton_2 = uibutton(app.Experiencia1Tab, 'push');
            app.ReproducirButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButton_2Pushed, true);
            app.ReproducirButton_2.Text = 'Reproducir';
            app.ReproducirButton_3 = uibutton(app.Experiencia1Tab, 'push');
            app.ReproducirButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButton_3Pushed, true);
            app.ReproducirButton_3.Text = 'Reproducir';
            app.GuardarButton_2 = uibutton(app.Experiencia1Tab, 'push');
            app.GuardarButton_2.ButtonPushedFcn = createCallbackFcn(app, @GuardarButton_2Pushed, true);
            app.GuardarButton_2.Text = 'Guardar';
            app.GuardarButton_3 = uibutton(app.Experiencia1Tab, 'push');
            app.GuardarButton_3.ButtonPushedFcn = createCallbackFcn(app, @GuardarButton_3Pushed, true);
            app.GuardarButton_3.Text = 'Guardar';
            app.GuardarButton = uibutton(app.Experiencia1Tab, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.SinRecuantizarButtonGroup = uibuttongroup(app.Experiencia1Tab);
            app.SinRecuantizarButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SinRecuantizarButtonGroupSelectionChanged, true);
            app.SinRecuantizarButtonGroup.Title = 'Sin Recuantizar';
            app.TiempoButton = uiradiobutton(app.SinRecuantizarButtonGroup);
            app.TiempoButton.Text = 'Tiempo';
            app.TiempoButton.Value = true;
            app.MagSpectrumButton = uiradiobutton(app.SinRecuantizarButtonGroup);
            app.MagSpectrumButton.Text = 'MagSpectrum';
            app.EspectrogramaButton = uiradiobutton(app.SinRecuantizarButtonGroup);
            app.EspectrogramaButton.Text = 'Espectrograma';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TP_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end